classdef Data_Export < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataExport                   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DataSourcePanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Mode_Merged_Button           matlab.ui.control.StateButton
        Mode_Quanti_Button           matlab.ui.control.StateButton
        GridLayout3                  matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        MaskFileOutputFormatPanel    matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        MaskFileDropDown             matlab.ui.control.DropDown
        MaskFileDropDownLabel        matlab.ui.control.Label
        SecondaryDropDownLabel       matlab.ui.control.Label
        SecondaryDropDown            matlab.ui.control.DropDown
        DataPanel                    matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        MeanCheckBox                 matlab.ui.control.CheckBox
        MedianCheckBox               matlab.ui.control.CheckBox
        ModeCheckBox                 matlab.ui.control.CheckBox
        DataLabel                    matlab.ui.control.Label
        UncertaintiesLabel           matlab.ui.control.Label
        StandarddeviationCheckBox    matlab.ui.control.CheckBox
        BRCCheckBox                  matlab.ui.control.CheckBox
        SizeEditFieldLabel           matlab.ui.control.Label
        SizeEditField                matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        PlotButton                   matlab.ui.control.Button
        MasksSubmasksLabel           matlab.ui.control.Label
        IncludebothMaskSubMasksCheckBox  matlab.ui.control.CheckBox
        IncludeSubMasksCheckBox      matlab.ui.control.CheckBox
        MetadataPanel                matlab.ui.container.Panel
        GridLayout5_2                matlab.ui.container.GridLayout
        SampleNameEditField          matlab.ui.control.EditField
        SampleNameEditFieldLabel     matlab.ui.control.Label
        PressureGPaEditField         matlab.ui.control.NumericEditField
        PressureGPaEditFieldLabel    matlab.ui.control.Label
        TemperatureCEditField        matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel   matlab.ui.control.Label
        PrintAssemblageCheckBox      matlab.ui.control.CheckBox
        Include_SampleCheckBox       matlab.ui.control.CheckBox
        Include_PressureCheckBox     matlab.ui.control.CheckBox
        Include_TemperatureCheckBox  matlab.ui.control.CheckBox
        GenerateSaveButton           matlab.ui.control.Button
        ExportFormatDropDown         matlab.ui.control.DropDown
        ExportFormatDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        XMapToolsApp 
        
        GeneratedDataCellFormat 
        GeneratedLabelCellFormat
        
        BRC
        
        WaitBar 
        
        
    end
    
    methods (Access = private)
        
        function update_export_source(app)
            
            % turn off the other buttons when they will be there. 
            
            %if isequal(app.Mode_Merged_Button.Value,0)
                %app.Mode_Merged_Button.Value = 1;
            %end
            
            if isequal(app.Mode_Merged_Button.Value,1)
                app.SecondaryDropDown.Items = app.XMapToolsApp.XMapToolsData.MapData.Me.Names;
                app.SecondaryDropDown.ItemsData = [1:length(app.SecondaryDropDown.Items)];
                app.SecondaryDropDownLabel.Text = "Merged";
            end
            
            if isequal(app.Mode_Quanti_Button.Value,1)
                app.SecondaryDropDown.Items = app.XMapToolsApp.XMapToolsData.MapData.Qt.Names;
                app.SecondaryDropDown.ItemsData = [1:length(app.SecondaryDropDown.Items)];
                app.SecondaryDropDownLabel.Text = "Quanti";
            end
            
            
        end
        
        function update_gui_method(app)
            
            switch app.ExportFormatDropDown.Value
                case 'ThermoFit'
                    app.ModeCheckBox.Value = 1;
                    app.MedianCheckBox.Value = 0;
                    app.MeanCheckBox.Value = 0;
                    
                    app.StandarddeviationCheckBox.Value = 1;
                    
                    app.PrintAssemblageCheckBox.Value = 1;
                    app.Include_SampleCheckBox.Value = 1;
                    app.Include_PressureCheckBox.Value = 1;
                    app.Include_TemperatureCheckBox.Value = 1;
                    
                case 'MinPlot'
                    app.ModeCheckBox.Value = 0;
                    app.MedianCheckBox.Value = 1;
                    app.MeanCheckBox.Value = 0;
                    
                    app.StandarddeviationCheckBox.Value = 0;
                    
                    app.PrintAssemblageCheckBox.Value = 0;
                    app.Include_SampleCheckBox.Value = 0;
                    app.Include_PressureCheckBox.Value = 0;
                    app.Include_TemperatureCheckBox.Value = 0;
            end
        end
        
        function GenerateCellFromSelectedDataIncludingSubmasks(app)
            
            MaskFile = app.XMapToolsApp.XMapToolsData.MapData.MaskFile;
            
            if isequal(app.Mode_Merged_Button.Value,1)
                Data_Sel = app.XMapToolsApp.XMapToolsData.MapData.Me;
                ElemList = Data_Sel.Data(app.SecondaryDropDown.Value).ElNames;
            end
            
            if isequal(app.Mode_Quanti_Button.Value,1)
                Data_Sel = app.XMapToolsApp.XMapToolsData.MapData.Qt;
                ElemList = Data_Sel.Data(app.SecondaryDropDown.Value).ElNames;
                
                %keyboard
            end
            
            PhaseList = MaskFile.Masks(app.MaskFileDropDown.Value).Names(2:end);

            MaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).MaskMap;
            
            PhaseList = {};
            IsSubMask = [];     % 1 is a submask, 0 is not.
            MaskIdx = [];
            SubMaskIdx = [];
            Compt = 0;
            for i = 2:length(MaskFile.Masks(app.MaskFileDropDown.Value).Names)
                if isempty(MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(i).Names)
                    Compt = Compt+1;
                    PhaseList{Compt} = MaskFile.Masks(app.MaskFileDropDown.Value).Names{i};
                    IsSubMask(Compt) = 0;
                    MaskIdx(Compt) = i;
                    SubMaskIdx(Compt) = 0;
                else
                    if isequal(app.IncludeSubMasksCheckBox.Value,1)
                        if isequal(app.IncludebothMaskSubMasksCheckBox.Value,1)
                            % we include both
                            Compt = Compt+1;
                            PhaseList{Compt} = MaskFile.Masks(app.MaskFileDropDown.Value).Names{i};
                            IsSubMask(Compt) = 0;
                            MaskIdx(Compt) = i;
                            SubMaskIdx(Compt) = 0;
                        end
                        for j = 2:length(MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(i).Names)
                            Compt = Compt+1;
                            PhaseList{Compt} = MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(i).Names{j};
                            IsSubMask(Compt) = 1;
                            MaskIdx(Compt) = i;
                            SubMaskIdx(Compt) = j-1;
                        end
                    else
                        Compt = Compt+1;
                        PhaseList{Compt} = MaskFile.Masks(app.MaskFileDropDown.Value).Names{i};
                        IsSubMask(Compt) = 0;
                        MaskIdx(Compt) = i;
                        SubMaskIdx(Compt) = 0;
                    end
                end
            end
            
            % Export of compositions:
            NbOutput = 0;
            if app.ModeCheckBox.Value
                NbOutput = NbOutput + 1;
                PosModes = NbOutput;
            end
            if app.MedianCheckBox.Value
                NbOutput = NbOutput + 1;
                Posmedian = NbOutput;
            end
            if app.MeanCheckBox.Value
                NbOutput = NbOutput + 1;
                Posmean = NbOutput;
            end
            
            CellData = cell(NbOutput * length(PhaseList),length(ElemList) + 1);
            
            CountData = 0;
            
            for i = 1:length(PhaseList)
                PhaseMap = zeros(size(MaskMap));
                
                if IsSubMask(i)
                    SubMaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(MaskIdx(i)).MaskSelMaskMap;
                    Idx = find(SubMaskMap == SubMaskIdx(i));
                    PhaseMap(Idx) = 1;
                else
                    Idx = find(MaskMap == MaskIdx(i)-1);
                    PhaseMap(Idx) = 1;
                end
                
                % figure, imagesc(PhaseMap), axis image, colorbar
                
                PhaseMap = PhaseMap .* app.BRC;
                % figure, imagesc(PhaseMap), axis image, colorbar
                
                Idx = find(PhaseMap);
                
                count = 0;
                for j = 1:length(ElemList) 
                    PxData = Data_Sel.Data(app.SecondaryDropDown.Value).CData(j).Map(Idx);
                    if app.ModeCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+PosModes+count,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+PosModes,2} = 'mode(px)';
                        end
                        ModeVal = mode(PxData);
                        if abs((ModeVal - mean(PxData))/ModeVal) > 0.10  % 10% deviation to mean = problem in the mode determination
                            [X,edges] = histcounts(PxData);
                            [MaxX,PosMaxX] = max(X);
                            ModeVal = edges(PosMaxX);       % close enough: this should be rare
                            disp('The mode was calculated using an histogram')
                        end
                        CellData{NbOutput*(i-1)+PosModes,j+2} = ModeVal;
                    end
                    if app.MedianCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+Posmedian,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+Posmedian,2} = 'median(px)';
                        end
                        CellData{NbOutput*(i-1)+Posmedian,j+2} = median(PxData);
                    end
                    if app.MeanCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+Posmean,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+Posmean,2} = 'mean(px)';
                        end
                        CellData{NbOutput*(i-1)+Posmean,j+2} = mean(PxData);
                    end
                end
            end
            
            Labels = ['mineral','analysis_type',ElemList];
            
            % Add columns for other selections
            if app.Include_SampleCheckBox.Value
                SampleNames = cell(size(CellData,1),1);
                [SampleNames{:}] = deal(app.SampleNameEditField.Value);
                CellData = [SampleNames,CellData(:,1:end)];
                Labels = ['sample',Labels];
            end
            
            if app.Include_PressureCheckBox.Value
                PressureValues = cell(size(CellData,1),1);
                [PressureValues{:}] = deal(app.PressureGPaEditField.Value);
                CellData = [CellData(:,1:end),PressureValues];
                Labels = [Labels,'pressure_gpa'];
            end
            
            if app.Include_TemperatureCheckBox.Value
                TemperatureValues = cell(size(CellData,1),1);
                [TemperatureValues{:}] = deal(app.TemperatureCEditField.Value);
                CellData = [CellData(:,1:end),TemperatureValues];
                Labels = [Labels,'temperature_degreeC'];
            end
            
            app.GeneratedDataCellFormat = CellData;
            app.GeneratedLabelCellFormat = Labels;
            
        end
        
        
        function GenerateCellFromSelectedData(app)
            
            MaskFile = app.XMapToolsApp.XMapToolsData.MapData.MaskFile;
            Merged = app.XMapToolsApp.XMapToolsData.MapData.Me;
            
            ElemList = Merged.Data(app.SecondaryDropDown.Value).ElNames;
            PhaseList = MaskFile.Masks(app.MaskFileDropDown.Value).Names(2:end);

            MaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).MaskMap;
            
            % Export of compositions:
            NbOutput = 0;
            if app.ModeCheckBox.Value
                NbOutput = NbOutput + 1;
                PosModes = NbOutput;
            end
            if app.MedianCheckBox.Value
                NbOutput = NbOutput + 1;
                Posmedian = NbOutput;
            end
            if app.MeanCheckBox.Value
                NbOutput = NbOutput + 1;
                Posmean = NbOutput;
            end
            
            CellData = cell(NbOutput * length(PhaseList),length(ElemList) + 1);
            
            CountData = 0;
               
            for i = 1:length(PhaseList)
                PhaseMap = zeros(size(MaskMap));
                Idx = find(MaskMap == i);
                PhaseMap(Idx) = 1;
                % figure, imagesc(PhaseMap), axis image, colorbar
                
                PhaseMap = PhaseMap .* app.BRC;
                % figure, imagesc(PhaseMap), axis image, colorbar
                
                Idx = find(PhaseMap);
                
                count = 0;
                for j = 1:length(ElemList)
                    PxData = Merged.Data.CData(j).Map(Idx);
                    if app.ModeCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+PosModes+count,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+PosModes,2} = 'mode(px)';
                        end
                        ModeVal = mode(PxData);
                        if abs((ModeVal - mean(PxData))/ModeVal) > 0.10  % 10% deviation to mean = problem in the mode determination
                            [X,edges] = histcounts(PxData);
                            [MaxX,PosMaxX] = max(X);
                            ModeVal = edges(PosMaxX);       % close enough: this should be rare
                            disp('The mode was calculated using an histogram')
                        end
                        CellData{NbOutput*(i-1)+PosModes,j+2} = ModeVal;
                    end
                    if app.MedianCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+Posmedian,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+Posmedian,2} = 'median(px)';
                        end
                        CellData{NbOutput*(i-1)+Posmedian,j+2} = median(PxData);
                    end
                    if app.MeanCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+Posmean,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+Posmean,2} = 'mean(px)';
                        end
                        CellData{NbOutput*(i-1)+Posmean,j+2} = mean(PxData);
                    end
                end
            end
            
            Labels = ['mineral','analysis_type',ElemList];
            
            % Add columns for other selections
            if app.Include_SampleCheckBox.Value
                SampleNames = cell(size(CellData,1),1);
                [SampleNames{:}] = deal(app.SampleNameEditField.Value);
                CellData = [SampleNames,CellData(:,1:end)];
                Labels = ['sample',Labels];
            end
            
            if app.Include_PressureCheckBox.Value
                PressureValues = cell(size(CellData,1),1);
                [PressureValues{:}] = deal(app.PressureGPaEditField.Value);
                CellData = [CellData(:,1:end),PressureValues];
                Labels = [Labels,'pressure_gpa'];
            end
            
            if app.Include_TemperatureCheckBox.Value
                TemperatureValues = cell(size(CellData,1),1);
                [TemperatureValues{:}] = deal(app.TemperatureCEditField.Value);
                CellData = [CellData(:,1:end),TemperatureValues];
                Labels = [Labels,'temperature_degreeC'];
            end
            
            app.GeneratedDataCellFormat = CellData;
            app.GeneratedLabelCellFormat = Labels;
            
        end
        
        
        function CalculateBRC(app)
            
            TheNbPx = app.SizeEditField.Value;
            TheNbPxOnGarde = app.ThresholdEditField.Value;
            
            MaskFile = app.XMapToolsApp.XMapToolsData.MapData.MaskFile;
            
            MaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).MaskMap;
            MaskNames = MaskFile.Masks(app.MaskFileDropDown.Value).Names;
            
            % Proceed to the correction
            TheLin = size(MaskMap,1);
            TheCol = size(MaskMap,2);
            %CoordMatrice = reshape([1:TheLin*TheCol],TheLin,TheCol);
            
            TheMaskFinal = zeros(size(MaskMap));
            
            Position = round(TheNbPx/2);
            TheNbPxInSel = TheNbPx^2;
            TheCriterion = TheNbPxInSel*TheNbPxOnGarde/100;
            
            for i=1:length(MaskNames) - 1                % for each phase
                
                TheMask = zeros(size(MaskMap));
                VectorOk = find(MaskMap == i);
                
                TheMask(VectorOk) = ones(size(VectorOk));
                
                TheWorkingMat = zeros(size(TheMask,1)*size(TheMask,2),TheNbPxInSel+1);
                
                VectMask = TheMask(:);
                TheWorkingMat(find(VectMask)) = 1000*ones(size(find(VectMask)));
                
                Compt = 1;
                for iLin = 1:TheNbPx
                    
                    
                    for iCol = 1:TheNbPx
                        
                        % SCAN
                        TheTempMat = zeros(size(TheMask));
                        TheTempMat(Position:end-(Position-1),Position:end-(Position-1)) = TheMask(iLin:end-(TheNbPx-iLin),iCol:end-(TheNbPx-iCol));
                        Compt = Compt+1;
                        TheWorkingMat(:,Compt) = TheTempMat(:);
                        
                    end
                end
                
                TheSum = sum(TheWorkingMat,2);
                OnVire1 = find(TheSum < 1000+TheCriterion & TheSum > 1000);
                TheMaskFinal(OnVire1) = ones(size(OnVire1));
                
            end
            
            app.BRC = ones(size(TheMaskFinal));
            app.BRC(find(TheMaskFinal(:) == 1)) = zeros(length(find(TheMaskFinal(:) == 1)),1);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp)
            
            app.DataExport.Visible = 'off';
            
            movegui(app.DataExport,'center');
            
            app.XMapToolsApp = XMapToolsApp;
            
            app.MaskFileDropDown.Items = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Names;
            app.MaskFileDropDown.ItemsData = [1:length(app.MaskFileDropDown.Items)];
            
            app.Mode_Merged_Button.Value = 1;
            update_export_source(app);
            
            update_gui_method(app);
            
            CalculateBRC(app);
            
            app.DataExport.Visible = 'on';
            
        end

        % Value changed function: MaskFileDropDown
        function MaskFileDropDownValueChanged(app, event)
            CalculateBRC(app)
        end

        % Value changed function: ExportFormatDropDown
        function ExportFormatDropDownValueChanged(app, event)
            update_gui_method(app) 
        end

        % Value changed function: Mode_Merged_Button
        function Mode_Merged_ButtonValueChanged(app, event)
            if isequal(app.Mode_Merged_Button.Value,1)
                app.Mode_Quanti_Button.Value = 0;
            else
                app.Mode_Quanti_Button.Value = 1;
            end
            update_export_source(app);
        end

        % Value changed function: Mode_Quanti_Button
        function Mode_Quanti_ButtonValueChanged(app, event)
            if isequal(app.Mode_Quanti_Button.Value,1)
                app.Mode_Merged_Button.Value = 0;
            else
                app.Mode_Merged_Button.Value = 1;
            end
            update_export_source(app) 
        end

        % Button pushed function: GenerateSaveButton
        function GenerateSaveButtonPushed(app, event)
            
            GenerateCellFromSelectedDataIncludingSubmasks(app);
            
            T = cell2table(app.GeneratedDataCellFormat,'VariableNames',app.GeneratedLabelCellFormat);
            
            
            [Success,Message,MessageID] = mkdir('Exported-MinComp');
            DateStr = char(datestr(now));
            DateStr(find(DateStr == ' ')) = '_'; DateStr(find(DateStr == ':')) = '_';
            ProjectName = ['MinComp_',app.ExportFormatDropDown.Value,'_',DateStr];
            Directory = fullfile(cd,'Exported-MinComp');
            [Success,Message,MessageID] = mkdir(Directory);
            
            
            switch app.ExportFormatDropDown.Value
                
                case 'MinPlot'
                    
                    writetable(T,fullfile(Directory,[ProjectName,'.csv']));
                    
                    uialert(app.DataExport,'The file has been saved in the folder /Exported-MinComp','XMapTools','Icon','success');
                
                case 'Thermofit'
                    fid = fopen(fullfile(Directory,[ProjectName,'.txt']),'w');
                    fclose(fid);
            end
            
            
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            value = app.SizeEditField.Value;
            isodd = rem(value,2) == 1;
            if ~isodd
                app.SizeEditField.Value = 3;
                uialert(app.DataExport,'This parameter must be an odd value (3, 5, 7, etc.)','XMapTools');
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            app.WaitBar = uiprogressdlg(app.DataExport,'Title','XMapTools','Indeterminate','on');
            app.WaitBar.Message = 'Generating plots (can take some time)';
            
            MaskFile = app.XMapToolsApp.XMapToolsData.MapData.MaskFile;
            
            MaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).MaskMap;
            MaskNames = MaskFile.Masks(app.MaskFileDropDown.Value).Names(2:end);
            
            figure
            tiledlayout('flow')
            
            BRC = app.BRC;
            
            for i = 1:length(MaskNames)
                PhaseMap = zeros(size(MaskMap));
                Idx = find(MaskMap == i);
                PhaseMap(Idx) = 1;
                Idx2 = find(MaskMap == i & BRC == 0);
                PhaseMap(Idx2) = 2;
                
                nexttile
                imagesc(PhaseMap), axis image
                title(MaskNames{i})
            end
            
            colormap([1,1,1;0,0,0;1,0,0])
            
            close(app.WaitBar)
            
        end

        % Value changed function: IncludebothMaskSubMasksCheckBox
        function IncludebothMaskSubMasksCheckBoxValueChanged(app, event)
            if isequal(app.IncludebothMaskSubMasksCheckBox.Value,1)
                app.IncludeSubMasksCheckBox.Value = 1;
            end
        end

        % Value changed function: IncludeSubMasksCheckBox
        function IncludeSubMasksCheckBoxValueChanged(app, event)
            if isequal(app.IncludeSubMasksCheckBox.Value,0)
                app.IncludebothMaskSubMasksCheckBox.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataExport = uifigure('Visible', 'off');
            app.DataExport.Name = 'XMapTools Export Module';
            app.GridLayout = uigridlayout(app.DataExport);
            app.DataSourcePanel = uipanel(app.GridLayout);
            app.DataSourcePanel.Title = 'Data Source';
            app.GridLayout2 = uigridlayout(app.DataSourcePanel);
            app.Mode_Merged_Button = uibutton(app.GridLayout2, 'state');
            app.Mode_Merged_Button.ValueChangedFcn = createCallbackFcn(app, @Mode_Merged_ButtonValueChanged, true);
            app.Mode_Merged_Button.Icon = '236-folder.png';
            app.Mode_Merged_Button.Text = 'Merged';
            app.Mode_Quanti_Button = uibutton(app.GridLayout2, 'state');
            app.Mode_Quanti_Button.ValueChangedFcn = createCallbackFcn(app, @Mode_Quanti_ButtonValueChanged, true);
            app.Mode_Quanti_Button.Icon = '220-layers.png';
            app.Mode_Quanti_Button.Text = 'Quanti';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.MaskFileOutputFormatPanel = uipanel(app.GridLayout);
            app.MaskFileOutputFormatPanel.Title = 'Mask File & Output Format';
            app.GridLayout4 = uigridlayout(app.MaskFileOutputFormatPanel);
            app.MaskFileDropDown = uidropdown(app.GridLayout4);
            app.MaskFileDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskFileDropDownValueChanged, true);
            app.MaskFileDropDownLabel = uilabel(app.GridLayout4);
            app.MaskFileDropDownLabel.Text = 'Mask File';
            app.SecondaryDropDownLabel = uilabel(app.GridLayout4);
            app.SecondaryDropDownLabel.Text = 'Merged';
            app.SecondaryDropDown = uidropdown(app.GridLayout4);
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Data';
            app.GridLayout5 = uigridlayout(app.DataPanel);
            app.MeanCheckBox = uicheckbox(app.GridLayout5);
            app.MeanCheckBox.Text = 'Mean';
            app.MedianCheckBox = uicheckbox(app.GridLayout5);
            app.MedianCheckBox.Text = 'Median';
            app.ModeCheckBox = uicheckbox(app.GridLayout5);
            app.ModeCheckBox.Text = 'Mode';
            app.DataLabel = uilabel(app.GridLayout5);
            app.DataLabel.Text = 'Data:';
            app.UncertaintiesLabel = uilabel(app.GridLayout5);
            app.UncertaintiesLabel.Text = 'Uncertainties:';
            app.StandarddeviationCheckBox = uicheckbox(app.GridLayout5);
            app.StandarddeviationCheckBox.Text = 'Standard deviation';
            app.BRCCheckBox = uicheckbox(app.GridLayout5);
            app.BRCCheckBox.Text = 'BRC';
            app.BRCCheckBox.Value = true;
            app.SizeEditFieldLabel = uilabel(app.GridLayout5);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SizeEditField.Limits = [1 100];
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = 3;
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout5);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ThresholdEditField.Limits = [1 100];
            app.ThresholdEditField.Value = 80;
            app.PlotButton = uibutton(app.GridLayout5, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = '165-layers.png';
            app.PlotButton.Text = 'Plot';
            app.MasksSubmasksLabel = uilabel(app.GridLayout5);
            app.MasksSubmasksLabel.Text = 'Masks & Submasks';
            app.IncludebothMaskSubMasksCheckBox = uicheckbox(app.GridLayout5);
            app.IncludebothMaskSubMasksCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludebothMaskSubMasksCheckBoxValueChanged, true);
            app.IncludebothMaskSubMasksCheckBox.Text = 'Include both';
            app.IncludeSubMasksCheckBox = uicheckbox(app.GridLayout5);
            app.IncludeSubMasksCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeSubMasksCheckBoxValueChanged, true);
            app.IncludeSubMasksCheckBox.Text = 'Include submasks';
            app.IncludeSubMasksCheckBox.Value = true;
            app.MetadataPanel = uipanel(app.GridLayout);
            app.MetadataPanel.Title = 'Metadata';
            app.GridLayout5_2 = uigridlayout(app.MetadataPanel);
            app.SampleNameEditField = uieditfield(app.GridLayout5_2, 'text');
            app.SampleNameEditField.Value = 'none';
            app.SampleNameEditFieldLabel = uilabel(app.GridLayout5_2);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.PressureGPaEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.PressureGPaEditField.Limits = [0 50];
            app.PressureGPaEditField.Value = 0.5;
            app.PressureGPaEditFieldLabel = uilabel(app.GridLayout5_2);
            app.PressureGPaEditFieldLabel.Text = 'Pressure (GPa)';
            app.TemperatureCEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.TemperatureCEditField.Limits = [0 2000];
            app.TemperatureCEditField.Value = 600;
            app.TemperatureCEditFieldLabel = uilabel(app.GridLayout5_2);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.PrintAssemblageCheckBox = uicheckbox(app.GridLayout5_2);
            app.PrintAssemblageCheckBox.Text = 'Print Assemblage';
            app.Include_SampleCheckBox = uicheckbox(app.GridLayout5_2);
            app.Include_SampleCheckBox.Text = '';
            app.Include_PressureCheckBox = uicheckbox(app.GridLayout5_2);
            app.Include_PressureCheckBox.Text = '';
            app.Include_TemperatureCheckBox = uicheckbox(app.GridLayout5_2);
            app.Include_TemperatureCheckBox.Text = '';
            app.GenerateSaveButton = uibutton(app.GridLayout, 'push');
            app.GenerateSaveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSaveButtonPushed, true);
            app.GenerateSaveButton.Icon = '022-save-as.png';
            app.GenerateSaveButton.Text = 'Generate & Save ';
            app.ExportFormatDropDown = uidropdown(app.GridLayout);
            app.ExportFormatDropDown.Items = {'MinPlot', 'ThermoFit'};
            app.ExportFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportFormatDropDownValueChanged, true);
            app.ExportFormatDropDown.Value = 'MinPlot';
            app.ExportFormatDropDownLabel = uilabel(app.GridLayout);
            app.ExportFormatDropDownLabel.Text = 'Export Format';
            % Show the figure after all components are created
            app.DataExport.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Export(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataExport)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataExport)
        end
    end
end