classdef LogGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LogGeneratorGUI                 matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        ScanTab                         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        X_StartLabel                    matlab.ui.control.Label
        X_startEditField                matlab.ui.control.NumericEditField
        Y_StartLabel                    matlab.ui.control.Label
        Y_startEditField                matlab.ui.control.NumericEditField
        LaserdiametermEditFieldLabel_2  matlab.ui.control.Label
        LaserDiameterEditField          matlab.ui.control.NumericEditField
        ScanspeedmsLabel                matlab.ui.control.Label
        LaserDiameterEditField_2        matlab.ui.control.NumericEditField
        ApplyButton                     matlab.ui.control.Button
        SpotTab                         matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        Plot                            matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        DataFiles % Description
        SelectedCells % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DataFiles)
            %
            % XMapTools is a free software solution for the analysis of chemical maps
            % Copyright © 2022-2023 University of Bern, Institute of Geological Sciences, Pierre Lanari
            %
            % XMapTools is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or any
            % later version.
            %
            % XMapTools is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with XMapTools. If not, see https://www.gnu.org/licenses.
            
            app.LogGeneratorGUI.Visible = 'off';
            
            movegui(app.LogGeneratorGUI,"center");
            
            app.LogGeneratorGUI.Visible = 'on';
            
            
            app.DataFiles = DataFiles;
             
            app.UITable.ColumnFormat = {'char',{'Scan','Spot'},'numeric','numeric'};
            
            Data4Table = cell(length(app.DataFiles),4);
            
            for i = 1:length(app.DataFiles)
                Data4Table{i,1} = app.DataFiles(i).FileName(1:end-4);
                Data4Table{i,2} = 'Scan';
            end
            
            app.UITable.Data = Data4Table;
            
            
            keyboard
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            
            answer = inputdlg('Enter new value','XMapTools');
            
            for i = 1:size(app.SelectedCells,1)
                app.UITable.Data(app.SelectedCells(i,:)) = answer;
            end
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectedCells = event.Indices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LogGeneratorGUI = uifigure('Visible', 'off');
            app.LogGeneratorGUI.Name = 'Log Generator – XMapTools';
            app.GridLayout = uigridlayout(app.LogGeneratorGUI);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.GridLayout2 = uigridlayout(app.ScanTab);
            app.X_StartLabel = uilabel(app.GridLayout2);
            app.X_StartLabel.Text = 'X_Start';
            app.X_startEditField = uieditfield(app.GridLayout2, 'numeric');
            app.X_startEditField.Value = 1;
            app.Y_StartLabel = uilabel(app.GridLayout2);
            app.Y_StartLabel.Text = 'Y_Start';
            app.Y_startEditField = uieditfield(app.GridLayout2, 'numeric');
            app.Y_startEditField.Value = 1;
            app.LaserdiametermEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.LaserdiametermEditFieldLabel_2.Text = 'Laser diameter (µm)';
            app.LaserDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LaserDiameterEditField.Value = 15;
            app.ScanspeedmsLabel = uilabel(app.GridLayout2);
            app.ScanspeedmsLabel.Text = 'Scan speed (µm/s)';
            app.LaserDiameterEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.LaserDiameterEditField_2.Value = 5;
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.Text = 'Apply';
            app.SpotTab = uitab(app.TabGroup);
            app.SpotTab.Title = 'Spot';
            app.GridLayout2_2 = uigridlayout(app.SpotTab);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Plot = uiaxes(app.GridLayout);
            xlabel(app.Plot, 'Time')
            ylabel(app.Plot, 'Intensity')
            app.ContextMenu = uicontextmenu(app.LogGeneratorGUI);
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            % Show the figure after all components are created
            app.LogGeneratorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogGenerator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LogGeneratorGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LogGeneratorGUI)
        end
    end
end